classdef algorithm_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        servefileSpinner         matlab.ui.control.Spinner
        servefileSpinnerLabel    matlab.ui.control.Label
        serveimageSpinner        matlab.ui.control.Spinner
        serveimageSpinnerLabel   matlab.ui.control.Label
        CameraRespxSpinner       matlab.ui.control.Spinner
        CameraRespxSpinnerLabel  matlab.ui.control.Label
        UITableExpected          matlab.ui.control.Table
        UITableClacLite          matlab.ui.control.Table
        UITableRightLite         matlab.ui.control.Table
        UITableLeftLite          matlab.ui.control.Table
        UITableClacFull          matlab.ui.control.Table
        UITableRightFull         matlab.ui.control.Table
        UITableLeftFull          matlab.ui.control.Table
        UIAxesRightOriginal      matlab.ui.control.UIAxes
        UIAxesLeftOriginal       matlab.ui.control.UIAxes
        UIAxesRightFull          matlab.ui.control.UIAxes
        UIAxesLeftFull           matlab.ui.control.UIAxes
        UIAxesRightLite          matlab.ui.control.UIAxes
        UIAxesLeftLite           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        width
        height

        leftImdDat 
        rightImdDat
        
        imageLeft  
        imageRight 

        index
        serve_num
    end
    
    methods (Access = public)


        function image_gen(app)

           %Things to compare
           %   - Compare different heights of the ball
           %   - Overall Accuracy
           %   - Overall error tracker
           %   - 

           %Initialization Parameters
           server_ip   = '127.0.0.1';     %IP address of the Unity Server
           server_port = 55001;           %Server Port of the Unity Sever
           
           client = tcpclient(server_ip,server_port,"Timeout",20);
           fprintf(1,"Connected to server\n");
           
           app.width = app.CameraRespxSpinner.Value;
           if app.width == 1920
              app.height = 1080;
           else
              app.height = 480;
           end
           
           
           serve = readmatrix("/datFiles/serve1.dat"); %+ num2str(app.serve_num) + ".dat");
           
           dataSize = size(serve);
           numFrames = dataSize(1);
           
           sampledServeCounter = 1;
           framesTimeMs = 50;
           
           %for counter = 1:numFrames Replace 250 with counters, x,y,z
           %probably should be arrays
               x = serve(250,1);
               y = serve(250,3);
               z = serve(250,2);
               %z = z + 0.1625 + 0.37;

            
              image = blenderLink(client,app.width,app.height,x,y,z,0,0,0,"tennisBall");


              imageLeft = blenderLink(client, app.width, app.height, 0, -0.3, 11, 0, 0, 90, "Camera");
              imagesc(app.UIAxesLeftOriginal, imageLeft, [0, 255]) % Apply fixed intensity range


              imageRight = blenderLink(client, app.width, app.height, 0, 0.3, 11, 0, 0, 90, "Camera");
              imagesc(app.UIAxesRightOriginal, imageRight, [0, 255]) % Apply fixed intensity range

              imwrite(imageLeft, "blender_L_im_" + num2str(1) + ".jpg");
              imwrite(imageRight, "blender_R_im_" + num2str(1) + ".jpg");

              app.leftImdDat = imageDatastore({'blender_L_im_1.jpg'});
              app.rightImdDat = imageDatastore({'blender_R_im_1.jpg'});
           
              app.imageLeft  = readimage(app.leftImdDat, 1);
              app.imageRight = readimage(app.rightImdDat, 1);
           %end;


           %Exporting expected values to table, will need to be indexed
           T = table(y,x,z);
           app.UITableExpected.Data = T;

           %for              Second for loop for algorithms

                algorithm_lite(app);

                algorithm_full(app);
           %end

        end


        function algorithm_lite(app)

           % Convert to Grayscale
           grayImage_L = rgb2gray(app.imageLeft);
           grayImage_R = rgb2gray(app.imageRight);
                  
           % Edge Detection
           edgeImage_L = edge(grayImage_L, 'sobel');
           edgeImage_R = edge(grayImage_R, 'sobel');
                  
           % Circle Detection
           [centers_L, radii_L] = imfindcircles(edgeImage_L, [15 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.88);
           [centers_R, radii_R] = imfindcircles(edgeImage_R, [15 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.92);


           %% 7. Extract Centroids
           if ~isempty(centers_L)
               xLeft = centers_L(1,1);
               yLeft = centers_L(1,2);
               T = table(centers_L(:,1), centers_L(:,2));
               app.UITableLeftLite.Data = T;
           else
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftLite.Data = T;
           end
       
           if ~isempty(centers_R)
               xRight = centers_R(1,1);
               yRight = centers_R(1,2);
               T = table(centers_R(:,1), centers_R(:,2));
               app.UITableRightLite.Data = T;
           else
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableRightLite.Data = T;
           end
           %% 8. Display Results
           imagesc(app.UIAxesLeftLite, grayImage_L, [0, 255]); 
           colormap(app.UIAxesLeftLite, 'gray');
           hold(app.UIAxesLeftLite, 'on');
           if ~isempty(centers_L)
               viscircles(app.UIAxesLeftLite, centers_L, radii_L, 'EdgeColor', 'r');
               plot(app.UIAxesLeftLite, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
           end
           hold(app.UIAxesLeftLite, 'off');
           
           imagesc(app.UIAxesRightLite, grayImage_R, [0, 255]); 
           colormap(app.UIAxesRightLite, 'gray');
           hold(app.UIAxesRightLite, 'on');
           if ~isempty(centers_R)
               viscircles(app.UIAxesRightLite, centers_R, radii_R, 'EdgeColor', 'r');
               plot(app.UIAxesRightLite, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
           end
           hold(app.UIAxesRightLite, 'off');


          %% 9. Compute Depth (Z), Disparity (d), and Position (X, Y)
          if ~isnan(xLeft) && ~isnan(xRight)
              % Baseline, focal length, and pixel size
              b = 600;      % baseline [mm]
              f = 6;       % focal length [mm]
              ps = 0.006;  % pixel size [mm]
      
              % Camera sensor details
              cxLeft = 1920 / 2;  
              cyLeft = 1080 / 2;  
              cxRight = 1920 / 2;  
              cyRight = 1080 / 2;  
      
              % Compute disparity
              d = abs((xLeft - cxLeft) - (xRight - cxRight)) * ps;  % Disparity in mm
      
              % Compute depth (Z) in meters
              Z = ((b * f) / d) / 1000; % Depth in meters
              Z = Z + 0.0;
              % Compute X and Y positions in meters
              X = Z * ((xLeft - cxLeft) * ps) / f; % Left-right position
              Y = (1*(Z * ((yLeft - cyLeft) * ps) / f)); % Up-down position
              Z1 = 11 - Z + 0.5325;
              % Display computed values
              T = table(X, Y, Z1);
              app.UITableClacLite.Data = T;
      
          else
              disp('Ball not detected in both images.');
              T = table();
              app.UITableClacLite.Data = T;
          end
        end


        function algorithm_full(app)

           % Convert to Grayscale
           leftImgModSob  = rgb2gray(app.imageLeft);
           rightImgModSob = rgb2gray(app.imageRight);
           %leftImgModSob = medfilt2(leftImgModSob);
           %rightImgModSob = medfilt2(rightImgModSob);
           leftImgModSob = imadjust(leftImgModSob,[0.3 0.7],[]);
           rightImgModSob = imadjust(rightImgModSob,[0.3 0.7],[]);
           kernel = [-1 0 1; -2 0 2; -1 0 1];
 
           leftImgModSob  = conv2(leftImgModSob, kernel);
           rightImgModSob = conv2(rightImgModSob, kernel);
           %Needed for Sobel, WORSE for non-sobel
           leftImgModSob = imgaussfilt(leftImgModSob,2);
           rightImgModSob = imgaussfilt(rightImgModSob,2);
                  
           % Circle Detection
           [centers_L, radii_L] = imfindcircles(leftImgModSob, [15 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.88);
           [centers_R, radii_R] = imfindcircles(rightImgModSob, [15 30], 'ObjectPolarity', 'bright', 'Sensitivity', 0.92);


           %% 7. Extract Centroids
           if ~isempty(centers_L)
               xLeft = centers_L(1,1);
               yLeft = centers_L(1,2);
               T = table(centers_L(:,1), centers_L(:,2));
               app.UITableLeftFull.Data = T;
           else
               xLeft = NaN;
               yLeft = NaN;
               T = table();
               app.UITableLeftFull.Data = T;
           end
       
           if ~isempty(centers_R)
               xRight = centers_R(1,1);
               yRight = centers_R(1,2);
               T = table(centers_R(:,1), centers_R(:,2));
               app.UITableRightFull.Data = T;
           else
               xRight = NaN;
               yRight = NaN;
               T = table();
               app.UITableRightFull.Data = T;
           end
           %% 8. Display Results
           imagesc(app.UIAxesLeftFull, leftImgModSob, [0, 255]); 
           colormap(app.UIAxesLeftFull, 'gray');
           hold(app.UIAxesLeftFull, 'on');
           if ~isempty(centers_L)
               viscircles(app.UIAxesLeftFull, centers_L, radii_L, 'EdgeColor', 'r');
               plot(app.UIAxesLeftFull, xLeft, yLeft, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
           end
           hold(app.UIAxesLeftFull, 'off');
           
           imagesc(app.UIAxesRightFull, leftImgModSob, [0, 255]); 
           colormap(app.UIAxesRightFull, 'gray');
           hold(app.UIAxesRightFull, 'on');
           if ~isempty(centers_R)
               viscircles(app.UIAxesRightFull, centers_R, radii_R, 'EdgeColor', 'r');
               plot(app.UIAxesRightFull, xRight, yRight, 'rx', 'MarkerSize', 15, 'LineWidth', 2);
           end
           hold(app.UIAxesRightFull, 'off');


          %% 9. Compute Depth (Z), Disparity (d), and Position (X, Y)
          if ~isnan(xLeft) && ~isnan(xRight)
              % Baseline, focal length, and pixel size
              b = 600;      % baseline [mm]
              f = 6;       % focal length [mm]
              ps = 0.006;  % pixel size [mm]
      
              % Camera sensor details
              cxLeft = 1920 / 2;  
              cyLeft = 1080 / 2;  
              cxRight = 1920 / 2;  
              cyRight = 1080 / 2;  
      
              % Compute disparity
              d = abs((xLeft - cxLeft) - (xRight - cxRight)) * ps;  % Disparity in mm
      
              % Compute depth (Z) in meters
              Z = ((b * f) / d) / 1000; % Depth in meters
              Z = Z + 0.0;
              % Compute X and Y positions in meters
              X = Z * ((xLeft - cxLeft) * ps) / f; % Left-right position
              Y = (1*(Z * ((yLeft - cyLeft) * ps) / f)); % Up-down position
              Z1 = 11 - Z + 0.5325;
              % Display computed values
              T = table(X, Y, Z1);
              app.UITableClacFull.Data = T;
      
          else
              disp('Ball not detected in both images.');
              T = table();
              app.UITableClacFull.Data = T;
          end
        end


        function algorithm_py(app)

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Value changed function: CameraRespxSpinner
        function CameraRespxSpinnerValueChanged(app, event)
           app.width = app.CameraRespxSpinner.Value;
           image_gen(app);

        end

        % Value changed function: serveimageSpinner
        function serveimageSpinnerValueChanged(app, event)
            app.index = app.serveimageSpinner.Value;
            
            image_gen(app);
        end

        % Value changed function: servefileSpinner
        function servefileSpinnerValueChanged(app, event)
            app.serve_num = app.servefileSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLeftLite = uiaxes(app.UIFigure);
            title(app.UIAxesLeftLite, 'Left Matlab')
            xlabel(app.UIAxesLeftLite, 'X')
            ylabel(app.UIAxesLeftLite, 'Y')
            zlabel(app.UIAxesLeftLite, 'Z')
            app.UIAxesRightLite = uiaxes(app.UIFigure);
            title(app.UIAxesRightLite, 'Right Matlab')
            xlabel(app.UIAxesRightLite, 'X')
            ylabel(app.UIAxesRightLite, 'Y')
            zlabel(app.UIAxesRightLite, 'Z')
            app.UIAxesLeftFull = uiaxes(app.UIFigure);
            title(app.UIAxesLeftFull, 'Left Full')
            xlabel(app.UIAxesLeftFull, 'X')
            ylabel(app.UIAxesLeftFull, 'Y')
            zlabel(app.UIAxesLeftFull, 'Z')
            app.UIAxesRightFull = uiaxes(app.UIFigure);
            title(app.UIAxesRightFull, 'Right Full')
            xlabel(app.UIAxesRightFull, 'X')
            ylabel(app.UIAxesRightFull, 'Y')
            zlabel(app.UIAxesRightFull, 'Z')
            app.UIAxesLeftOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesLeftOriginal, 'Left  Original')
            xlabel(app.UIAxesLeftOriginal, 'X')
            ylabel(app.UIAxesLeftOriginal, 'Y')
            zlabel(app.UIAxesLeftOriginal, 'Z')
            app.UIAxesRightOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesRightOriginal, 'Right Original')
            xlabel(app.UIAxesRightOriginal, 'X')
            ylabel(app.UIAxesRightOriginal, 'Y')
            zlabel(app.UIAxesRightOriginal, 'Z')
            app.UITableLeftFull = uitable(app.UIFigure);
            app.UITableLeftFull.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftFull.RowName = {};
            app.UITableRightFull = uitable(app.UIFigure);
            app.UITableRightFull.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightFull.RowName = {};
            app.UITableClacFull = uitable(app.UIFigure);
            app.UITableClacFull.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacFull.RowName = {};
            app.UITableLeftLite = uitable(app.UIFigure);
            app.UITableLeftLite.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftLite.RowName = {};
            app.UITableRightLite = uitable(app.UIFigure);
            app.UITableRightLite.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightLite.RowName = {};
            app.UITableClacLite = uitable(app.UIFigure);
            app.UITableClacLite.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacLite.RowName = {};
            app.UITableExpected = uitable(app.UIFigure);
            app.UITableExpected.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableExpected.RowName = {};
            app.CameraRespxSpinnerLabel = uilabel(app.UIFigure);
            app.CameraRespxSpinnerLabel.Text = 'Camera Res (px)';
            app.CameraRespxSpinner = uispinner(app.UIFigure);
            app.CameraRespxSpinner.Step = 1168;
            app.CameraRespxSpinner.Limits = [752 1920];
            app.CameraRespxSpinner.ValueChangedFcn = createCallbackFcn(app, @CameraRespxSpinnerValueChanged, true);
            app.CameraRespxSpinner.Value = 1920;
            app.serveimageSpinnerLabel = uilabel(app.UIFigure);
            app.serveimageSpinnerLabel.Text = 'serve image';
            app.serveimageSpinner = uispinner(app.UIFigure);
            app.serveimageSpinner.Limits = [1 1773];
            app.serveimageSpinner.ValueChangedFcn = createCallbackFcn(app, @serveimageSpinnerValueChanged, true);
            app.serveimageSpinner.Value = 1;
            app.servefileSpinnerLabel = uilabel(app.UIFigure);
            app.servefileSpinnerLabel.Text = 'serve file';
            app.servefileSpinner = uispinner(app.UIFigure);
            app.servefileSpinner.Limits = [1 5];
            app.servefileSpinner.ValueChangedFcn = createCallbackFcn(app, @servefileSpinnerValueChanged, true);
            app.servefileSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = algorithm_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end