classdef labAssignment3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UITableExpected    matlab.ui.control.Table
        UITableClacRightM  matlab.ui.control.Table
        UITableClacLeftM   matlab.ui.control.Table
        UITableRightM      matlab.ui.control.Table
        UITableLeftM       matlab.ui.control.Table
        UITableClacRightA  matlab.ui.control.Table
        UITableClacLeftA   matlab.ui.control.Table
        UITableRightA      matlab.ui.control.Table
        UITableLeftA       matlab.ui.control.Table
        SpinnerIndex       matlab.ui.control.Spinner
        SpinnerLabel       matlab.ui.control.Label
        UIAxesRightM       matlab.ui.control.UIAxes
        UIAxesLeftM        matlab.ui.control.UIAxes
        UIAxesRightA       matlab.ui.control.UIAxes
        UIAxesLeftA        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageIndex;
        leftImds ;
        rightImds;
        xNumPix;
        yNumPix;
        leftImg 
        rightImg
        b 
        f 
        ps
        leftImdDat
        rightImdDat
        calcZArray
        expected
    end
    
    methods (Access = private)
        
        function algorithm_a(app)
            
            leftImgMod = app.leftImg;
            
            rightImgMod = app.rightImg;
           
            leftImgModSob  = rgb2gray(leftImgMod);
            rightImgModSob = rgb2gray(rightImgMod);
            %leftImgModSob = medfilt2(leftImgModSob);
            %rightImgModSob = medfilt2(rightImgModSob);
            leftImgModSob = imadjust(leftImgModSob,[0.3 0.7],[]);
            rightImgModSob = imadjust(rightImgModSob,[0.3 0.7],[]);
            kernel = [-1 0 1; -2 0 2; -1 0 1];
  
            leftImgModSob  = conv2(leftImgModSob, kernel);
            rightImgModSob = conv2(rightImgModSob, kernel);
            %Needed for Sobel, WORSE for non-sobel
            leftImgModSob = imgaussfilt(leftImgModSob,2);
            rightImgModSob = imgaussfilt(rightImgModSob,2);
           
            [leftC, leftR, leftM]    = imfindcircles(leftImgModSob, [15 20], 'ObjectPolarity', 'bright', 'Sensitivity', 0.93);
            [rightC, rightR, rightM] = imfindcircles(rightImgModSob, [15 20], 'ObjectPolarity', 'bright', 'Sensitivity', 0.93);
   
            imagesc(app.UIAxesLeftA, leftImgModSob);
            imagesc(app.UIAxesRightA, rightImgModSob);
   
            hold(app.UIAxesLeftA, 'on');
            hold(app.UIAxesRightA, 'on'); 
                
                
            if (size(leftC) > 0)
                leftC = leftC(1,:); 
                leftR = leftR(1);
                leftM = leftM(1);
            end

            if(size(leftC) > 0)
                scatter(app.UIAxesLeftA, leftC(:,1), leftC(:,2));
                T = table(leftC(:,1), leftC(:,2));
                app.UITableLeftA.Data = T;
                scatter(app.UIAxesLeftA, leftC(:,1), leftC(:,2));
            end
            
            if (size(rightC) > 0)
                rightC = rightC(1,:); 
                rightR = rightR(1);
                rightM = rightM(1);
            end

            if(size(rightC) > 0)
                scatter(app.UIAxesRightA, rightC(:,1), rightC(:,2));
                T = table(rightC(:,1), rightC(:,2));
                app.UITableRightA.Data = T;
            end

            viscircles(app.UIAxesLeftA, leftC, leftR);
            viscircles(app.UIAxesRightA, rightC, rightR); 
   
            hold(app.UIAxesLeftA, 'off');
            hold(app.UIAxesRightA, 'off');


            if ((size(rightC) > 0) & (size(leftC) > 0))
                d = (abs((leftC(1,1) - app.xNumPix/2) - (rightC(1,1) - app.xNumPix/2))*app.ps); %disparity [mm]
                Z = (app.b * app.f) ./ d; %depth [mm]
                calcZ = Z/1000;
                calcXL = (calcZ * ((leftC(1,1) - app.xNumPix/2)*app.ps)/app.f) - app.b/2000;
                calcYL = (calcZ * ((leftC(1,2) - app.yNumPix/2)*app.ps)/app.f);
                calcXR = (calcZ * ((rightC(1,1) - app.xNumPix/2)*app.ps)/app.f) - app.b/2000;
                calcYR = (calcZ * ((rightC(1,2) - app.yNumPix/2)*app.ps)/app.f);
                T = app.expected(app.imageIndex,:);
                app.UITableExpected.Data = T;
                T = table(calcXL, calcYL, calcZ);
                app.UITableClacLeftA.Data = T;
                T = table(calcXR, calcYR, calcZ);
                app.UITableClacRightA.Data = T;

                app.calcZArray(app.imageIndex) = calcZ;

            end
        end


        function algorithm_m(app)
            
            leftImgMod = app.leftImg;

            rightImgMod = app.rightImg;

            % Convert to Grayscale
            grayImage_L = rgb2gray(leftImgMod);
            grayImage_R = rgb2gray(rightImgMod);
        
            % Edge Detection
            edgeImage_L = edge(grayImage_L, 'sobel');
            edgeImage_R = edge(grayImage_R, 'sobel');
        
            % Circle Detection
            [leftC, leftR]    = imfindcircles(edgeImage_L, [15 20], 'ObjectPolarity', 'bright', 'Sensitivity', 0.93);
            [rightC, rightR] = imfindcircles(edgeImage_R, [15 20], 'ObjectPolarity', 'bright', 'Sensitivity', 0.93);
   
            imagesc(app.UIAxesLeftM, edgeImage_L);
            imagesc(app.UIAxesRightM, edgeImage_R);
   
            hold(app.UIAxesLeftM, 'on');
            hold(app.UIAxesRightM, 'on'); 
                
                
            if (size(leftC) > 0)
                leftC = leftC(1,:); 
                leftR = leftR(1);
            end

            if(size(leftC) > 0)
                scatter(app.UIAxesLeftM, leftC(:,1), leftC(:,2));
                T = table(leftC(:,1), leftC(:,2));
                app.UITableLeftM.Data = T;
                scatter(app.UIAxesLeftM, leftC(:,1), leftC(:,2));
            end
            
            if (size(rightC) > 0)
                rightC = rightC(1,:); 
                rightR = rightR(1);
            end

            if(size(rightC) > 0)
                scatter(app.UIAxesRightM, rightC(:,1), rightC(:,2));
                T = table(rightC(:,1), rightC(:,2));
                app.UITableRightM.Data = T;
            end

            viscircles(app.UIAxesLeftM, leftC, leftR);
            viscircles(app.UIAxesRightM, rightC, rightR); 
   
            hold(app.UIAxesLeftM, 'off');
            hold(app.UIAxesRightM, 'off');


            if ((size(rightC) > 0) & (size(leftC) > 0))

                % Camera centers
                cxLeft = 752 / 2;
                cyLeft = 480 / 2;
                cxRight = 752 / 2;
                cyRight = 480 / 2;
        
                % Compute disparity
                d = abs((leftC(1,1) - cxLeft) - (rightC(1,1) - cxRight)) * app.ps;  % Disparity in mm
        
                % Compute Depth (Z) in meters
                calcZ = ((app.b * app.f) / d) / 1000; 
        
                % Compute X and Y positions in meters
                calcXL = calcZ * ((leftC(1,1) - cxLeft) * app.ps) / app.f; % Left-right position
                calcYL = -1 * (calcZ * ((leftC(1,2) - cyLeft) * app.ps) / app.f); % Up-down position

                calcXR = calcZ * ((rightC(1,1) - cxRight) * app.ps) / app.f; % Left-right position
                calcYR = -1 * (calcZ * ((rightC(1,2) - cyRight) * app.ps) / app.f); % Up-down position

                T = app.expected(app.imageIndex,:);
                app.UITableExpected.Data = T;
                T = table(calcXL, calcYL, calcZ);
                app.UITableClacLeftM.Data = T;
                T = table(calcXR, calcYR, calcZ);
                app.UITableClacRightM.Data = T;

                app.calcZArray(app.imageIndex) = calcZ;

            end
            

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xNumPix = 752;
            app.yNumPix = 480;

            app.b = 1000;             %baseline [mm]
            app.f = 18;              %focal length [mm]
            app.ps = 0.006;         %pixel size [mm]

            app.calcZArray = [3, 3.25, 3.5, 3.75, 4, 4.25, 4.5, 4.75, 5];

            app.imageIndex = app.SpinnerIndex.Value;

            xlim(app.UIAxesLeftA, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesLeftA, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesRightA, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesRightA, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesLeftM, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesLeftM, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesRightM, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesRightM, [0 (app.yNumPix-1)]);

            %Initialization Parameters
            server_ip   = '127.0.0.1';     %IP address of the Unity Server
            server_port = 55001;           %Server Port of the Unity Sever

            client = tcpclient(server_ip,server_port,"Timeout",20);
            fprintf(1,"Connected to server\n");

            width = 720;
            height = 480;
            app.expected = [];
            blenderLink(client,width,height,1,1,0.5,0,0,0,"tennisBall");
            x = 1;
            y = 1;
            z = 0.5;
            
            for i =  1:9
                app.expected(i,:) = [x, y, z];
                leftIm  = blenderLink(client,width,height,-1,0.039207,35,0,0,0,"Camera");
                rightIm = blenderLink(client,width,height, 1,0.039207,35,0,0,0,"Camera");
                imwrite(leftIm, "blender_L_im_" + num2str(i) + ".jpg");
                imwrite(rightIm, "blender_R_im_" + num2str(i) + ".jpg");
                x = x + 1;
                y = y + 0.5;
                z = z;
                blenderLink(client,width,height,(x),(y),z,0,0,0,"tennisBall");
                disp(app.expected)
            end
            
            app.leftImdDat = imageDatastore({'blender_L_im_1.jpg', 'blender_L_im_2.jpg','blender_L_im_3.jpg','blender_L_im_4.jpg','blender_L_im_5.jpg', 'blender_L_im_6.jpg','blender_L_im_7.jpg','blender_L_im_8.jpg','blender_L_im_9.jpg'});
            app.rightImdDat = imageDatastore({'blender_R_im_1.jpg', 'blender_R_im_2.jpg','blender_R_im_3.jpg','blender_R_im_4.jpg','blender_R_im_5.jpg', 'blender_R_im_6.jpg','blender_R_im_7.jpg','blender_R_im_8.jpg','blender_R_im_9.jpg'});

            app.leftImg  = readimage(app.leftImdDat, app.imageIndex);
            app.rightImg = readimage(app.rightImdDat, app.imageIndex);

            imagesc(app.UIAxesLeftA, app.leftImg);
            imagesc(app.UIAxesRightA, app.rightImg);
            algorithm_a(app)
            algorithm_m(app)
        end

        % Value changed function: SpinnerIndex
        function SpinnerIndexValueChanged(app, event)
            app.imageIndex = app.SpinnerIndex.Value;
            app.leftImg  = readimage(app.leftImdDat, app.imageIndex);
            app.rightImg = readimage(app.rightImdDat, app.imageIndex);

            imagesc(app.UIAxesLeftA, app.leftImg);
            imagesc(app.UIAxesRightA, app.rightImg); 
            algorithm_a(app)
            algorithm_m(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLeftA = uiaxes(app.UIFigure);
            title(app.UIAxesLeftA, 'Left A')
            xlabel(app.UIAxesLeftA, 'X')
            ylabel(app.UIAxesLeftA, 'Y')
            zlabel(app.UIAxesLeftA, 'Z')
            app.UIAxesRightA = uiaxes(app.UIFigure);
            title(app.UIAxesRightA, 'Right A')
            xlabel(app.UIAxesRightA, 'X')
            ylabel(app.UIAxesRightA, 'Y')
            zlabel(app.UIAxesRightA, 'Z')
            app.UIAxesLeftM = uiaxes(app.UIFigure);
            title(app.UIAxesLeftM, 'Left M')
            xlabel(app.UIAxesLeftM, 'X')
            ylabel(app.UIAxesLeftM, 'Y')
            zlabel(app.UIAxesLeftM, 'Z')
            app.UIAxesRightM = uiaxes(app.UIFigure);
            title(app.UIAxesRightM, 'Right M')
            xlabel(app.UIAxesRightM, 'X')
            ylabel(app.UIAxesRightM, 'Y')
            zlabel(app.UIAxesRightM, 'Z')
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.SpinnerIndex = uispinner(app.UIFigure);
            app.SpinnerIndex.Limits = [1 9];
            app.SpinnerIndex.ValueChangedFcn = createCallbackFcn(app, @SpinnerIndexValueChanged, true);
            app.SpinnerIndex.Value = 1;
            app.UITableLeftA = uitable(app.UIFigure);
            app.UITableLeftA.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftA.RowName = {};
            app.UITableRightA = uitable(app.UIFigure);
            app.UITableRightA.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightA.RowName = {};
            app.UITableClacLeftA = uitable(app.UIFigure);
            app.UITableClacLeftA.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacLeftA.RowName = {};
            app.UITableClacRightA = uitable(app.UIFigure);
            app.UITableClacRightA.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacRightA.RowName = {};
            app.UITableLeftM = uitable(app.UIFigure);
            app.UITableLeftM.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeftM.RowName = {};
            app.UITableRightM = uitable(app.UIFigure);
            app.UITableRightM.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRightM.RowName = {};
            app.UITableClacLeftM = uitable(app.UIFigure);
            app.UITableClacLeftM.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacLeftM.RowName = {};
            app.UITableClacRightM = uitable(app.UIFigure);
            app.UITableClacRightM.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClacRightM.RowName = {};
            app.UITableExpected = uitable(app.UIFigure);
            app.UITableExpected.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableExpected.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labAssignment3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end