classdef lab2Assignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UITable          matlab.ui.control.Table
        slider_baseline  matlab.ui.control.Slider
        XLabel_3         matlab.ui.control.Label
        slider_focal     matlab.ui.control.Slider
        XLabel_2         matlab.ui.control.Label
        slider_z         matlab.ui.control.Slider
        ZmmLabel         matlab.ui.control.Label
        slider_y         matlab.ui.control.Slider
        YmmLabel         matlab.ui.control.Label
        slider_x         matlab.ui.control.Slider
        XLabel           matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        f % focal length [mm]
        b % baseline [mm]
        x % X [m]
        y % Y [m]
        z % Z [m]
        leftPixel
        rightPixel
        ps  
        xNumPix
        yNumPix
        camLeft
        camRight
    end
    
    methods (Access = private)
        
        function calculateDepth(app)
           cxLeft = app.xNumPix/2;
           cyLeft = app.yNumPix/2;
           cxRight = app.xNumPix/2;
           cyRight = app.yNumPix/2;
           
           P = [app.x;app.y;app.z];
           app.leftPixel = app.camLeft.project(P);
           app.rightPixel = app.camRight.project(P);
           xLeft = app.leftPixel(1,:);
           yLeft = app.leftPixel(2,:);
           xRight = app.rightPixel(1,:);
           yRight = app.rightPixel(2,:);
           d = (abs((xLeft - cxLeft)-(xRight-cxRight))*app.ps); %disparity [mm]
           Z = (app.b*app.f)./d; %depth [mm]
           calcZ = Z/1000;
           calcXL = calcZ * ((xLeft - cxLeft)*app.ps)/app.f;
           calcYL = calcZ * ((yLeft - cyLeft)*app.ps)/app.f;
           calcXR = calcZ * ((xRight - cxRight)*app.ps)/app.f;
           calcYR = calcZ * ((yRight - cyRight)*app.ps)/app.f;
           label = {"Actual"; "Left Calc"; "Right Calc"};
           xArray = [app.x; calcXL; calcXR];
           yArray = [app.y; calcYL; calcYR];
           zArray = [app.z; calcZ; calcZ];
           T = table(label, xArray, yArray, zArray);
           app.UITable.Data = T;
           scatter(app.UIAxes, app.leftPixel(1), app.leftPixel(2));
           hold(app.UIAxes, 'on');
           scatter(app.UIAxes, app.rightPixel(1), app.rightPixel(2));
           legend(app.UIAxes, "Left Camera","Right Camera");
           hold(app.UIAxes, 'off');
           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
           %Set defaults
           app.x = app.slider_x.Value;
           app.y = app.slider_y.Value;
           app.z = app.slider_z.Value;
           app.f = 6;
           app.b = app.slider_baseline.Value;
           app.ps = 0.006;   
           app.xNumPix = 752;
           app.yNumPix = 480;
           xlim(app.UIAxes, [0 (app.xNumPix-1)]);
           ylim(app.UIAxes, [0 (app.yNumPix-1)]);
           

           %Left Camera Creation
           app.camLeft = CentralCamera('focal', app.f/1000, 'pixel', app.ps/1000, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'Left');
           Tcam = SE3(-(app.b/2000),0,0);
           app.camLeft.T = Tcam;
        
           %Right Camera Creation
           app.camRight = CentralCamera('focal', app.f/1000, 'pixel', app.ps/1000, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'Right');
           Tcam = SE3((app.b/2000),0,0);
           app.camRight.T = Tcam;

           calculateDepth(app);


        end

        % Value changing function: slider_x
        function slider_xValueChanging(app, event)

        end

        % Value changed function: slider_x
        function slider_xValueChanged(app, event)
            app.x = app.slider_x.Value;
            app.calculateDepth
        end

        % Value changed function: slider_y
        function slider_yValueChanged(app, event)
            app.y = app.slider_y.Value;
            app.calculateDepth
        end

        % Value changed function: slider_z
        function slider_zValueChanged(app, event)
            app.z = app.slider_z.Value;
            app.calculateDepth
        end

        % Value changed function: slider_focal
        function slider_focalValueChanged(app, event)
            app.f = app.slider_focal.Value;
            disp(app.f);

           %Left Camera Creation
           app.camLeft = CentralCamera('focal', app.f/1000, 'pixel', app.ps/1000, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'Left');
           Tcam = SE3(-(app.b/2000),0,0);
           app.camLeft.T = Tcam;
        
           %Right Camera Creation
           app.camRight = CentralCamera('focal', app.f/1000, 'pixel', app.ps/1000, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'Right');
           Tcam = SE3((app.b/2000),0,0);
           app.camRight.T = Tcam;

           app.calculateDepth
        end

        % Value changed function: slider_baseline
        function slider_baselineValueChanged(app, event)
            app.b = app.slider_baseline.Value;
            
            %Left Camera Creation
            app.camLeft = CentralCamera('focal', app.f/1000, 'pixel', app.ps/1000, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'Left');
            Tcam = SE3(-(app.b/2000),0,0);
            app.camLeft.T = Tcam;
         
            %Right Camera Creation
            app.camRight = CentralCamera('focal', app.f/1000, 'pixel', app.ps/1000, 'resolution', [app.xNumPix, app.yNumPix], 'centre', [app.xNumPix/2, app.yNumPix/2], 'name', 'Right');
            Tcam = SE3((app.b/2000),0,0);
            app.camRight.T = Tcam;

            app.calculateDepth
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Left and Right Camera')
            xlabel(app.UIAxes, 'X [Pixels]')
            ylabel(app.UIAxes, 'Y [Pixels]')
            zlabel(app.UIAxes, 'Z')
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X [m]';
            app.slider_x = uislider(app.UIFigure);
            app.slider_x.Limits = [-25 25];
            app.slider_x.ValueChangedFcn = createCallbackFcn(app, @slider_xValueChanged, true);
            app.slider_x.ValueChangingFcn = createCallbackFcn(app, @slider_xValueChanging, true);
            app.YmmLabel = uilabel(app.UIFigure);
            app.YmmLabel.Text = 'Y [m]';
            app.slider_y = uislider(app.UIFigure);
            app.slider_y.Limits = [-25 25];
            app.slider_y.ValueChangedFcn = createCallbackFcn(app, @slider_yValueChanged, true);
            app.ZmmLabel = uilabel(app.UIFigure);
            app.ZmmLabel.Text = 'Z [m]';
            app.slider_z = uislider(app.UIFigure);
            app.slider_z.Limits = [0 50];
            app.slider_z.ValueChangedFcn = createCallbackFcn(app, @slider_zValueChanged, true);
            app.slider_z.Value = 2;
            app.XLabel_2 = uilabel(app.UIFigure);
            app.XLabel_2.Text = {'Focal Length'; ' [mm]       '};
            app.slider_focal = uislider(app.UIFigure);
            app.slider_focal.Limits = [0 50];
            app.slider_focal.ValueChangedFcn = createCallbackFcn(app, @slider_focalValueChanged, true);
            app.XLabel_3 = uilabel(app.UIFigure);
            app.XLabel_3.Text = {'Baseline'; '  [mm]   '};
            app.slider_baseline = uislider(app.UIFigure);
            app.slider_baseline.ValueChangedFcn = createCallbackFcn(app, @slider_baselineValueChanged, true);
            app.slider_baseline.Value = 30;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''; 'X [m]'; 'Y [m]'; 'Z [m]'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2Assignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end