classdef labAssignment3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UITableClac          matlab.ui.control.Table
        UITableRight         matlab.ui.control.Table
        StrongestButton      matlab.ui.control.Button
        UITableLeft          matlab.ui.control.Table
        BlueMaxSlider        matlab.ui.control.Slider
        BlueMaxSliderLabel   matlab.ui.control.Label
        BlueMinSlider        matlab.ui.control.Slider
        BlueMinSliderLabel   matlab.ui.control.Label
        GreenMaxSlider       matlab.ui.control.Slider
        GreenMaxSliderLabel  matlab.ui.control.Label
        GreenMinSlider       matlab.ui.control.Slider
        GreenMinSliderLabel  matlab.ui.control.Label
        RedMaxSlider         matlab.ui.control.Slider
        RedMaxSliderLabel    matlab.ui.control.Label
        RedMinSlider         matlab.ui.control.Slider
        RedMinSliderLabel    matlab.ui.control.Label
        SobelButton          matlab.ui.control.Button
        SpinnerIndex         matlab.ui.control.Spinner
        SpinnerLabel         matlab.ui.control.Label
        UIAxesLeftBlob       matlab.ui.control.UIAxes
        UIAxesRightBlob      matlab.ui.control.UIAxes
        UIAxesRightMod       matlab.ui.control.UIAxes
        UIAxesLeftMod        matlab.ui.control.UIAxes
        UIAxesRightMain      matlab.ui.control.UIAxes
        UIAxesLeftMain       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imageIndex;
        leftImds ;
        rightImds;
        xNumPix;
        yNumPix;
        leftImg 
        rightImg
        redMinValue
        redMaxValue
        greenMinValue
        greenMaxValue
        blueMinValue
        blueMaxValue
        sobelTrue
        strongest
        blobTrue
        hBlob
        b 
        f 
        ps
    end
    
    methods (Access = private)
        
        function sobel(app)

            imagesc(app.UIAxesLeftMain, app.leftImg);
            imagesc(app.UIAxesRightMain, app.rightImg);
            
            app.leftImg  = rgb2gray(app.leftImg);
            app.rightImg = rgb2gray(app.rightImg);
            
            boxcar = ones(3,3)./9;

            app.leftImg  = conv2(app.leftImg, boxcar);
            app.rightImg = conv2(app.rightImg, boxcar);

            kernel = [-1 0 1; -2 0 2; -1 0 1];
               
            app.leftImg  = conv2(app.leftImg, kernel);
            app.rightImg = conv2(app.rightImg, kernel);

            %leftImg  = edge(leftImg, 'Sobel');
            %rightImg = edge(rightImg, 'Sobel');


        end
        
        function modify_img(app)
            redChannel   = app.leftImg(:,:,1);
            greenChannel = app.leftImg(:,:,2);
            blueChannel  = app.leftImg(:,:,3);


            indices = find(redChannel < app.redMinValue);
            redChannel(indices) = 0;
            indices = find(redChannel > app.redMaxValue);
            redChannel(indices) = 0;

            indices = find(greenChannel < app.greenMinValue);
            greenChannel(indices) = 0;
            indices = find(greenChannel > app.greenMaxValue);
            greenChannel(indices) = 0;

            indices = find(blueChannel < app.blueMinValue);
            blueChannel(indices) = 0;
            indices = find(blueChannel > app.blueMaxValue);
            blueChannel(indices) = 0;

            leftImgMod = app.leftImg;
            leftImgMod(:,:,1) = redChannel;
            leftImgMod(:,:,2) = greenChannel;
            leftImgMod(:,:,3) = blueChannel;

            redChannel   = app.rightImg(:,:,1);
            greenChannel = app.rightImg(:,:,2);
            blueChannel  = app.rightImg(:,:,3);

            indices = find(redChannel < app.redMinValue);
            redChannel(indices) = 0;
            indices = find(redChannel > app.redMaxValue);
            redChannel(indices) = 0;

            indices = find(greenChannel < app.greenMinValue);
            greenChannel(indices) = 0;
            indices = find(greenChannel > app.greenMaxValue);
            greenChannel(indices) = 0;

            indices = find(blueChannel < app.blueMinValue);
            blueChannel(indices) = 0;
            indices = find(blueChannel > app.blueMaxValue);
            blueChannel(indices) = 0;

            rightImgMod = app.rightImg;
            rightImgMod(:,:,1) = redChannel;
            rightImgMod(:,:,2) = greenChannel;
            rightImgMod(:,:,3) = blueChannel;

            if(1 == app.sobelTrue)
                leftImgModSob  = rgb2gray(leftImgMod);
                rightImgModSob = rgb2gray(rightImgMod);

                leftImgModSob = medfilt2(leftImgModSob);
                rightImgModSob = medfilt2(rightImgModSob);

                boxcar = ones(3,3); %./9;

                leftImgModSob  = conv2(leftImgModSob, boxcar);
                rightImgModSob = conv2(rightImgModSob, boxcar);

                kernel = [-1 0 1; -2 0 2; -1 0 1];
   
                leftImgModSob  = conv2(leftImgModSob, kernel);
                rightImgModSob = conv2(rightImgModSob, kernel);
            
                [leftC, leftR, leftM] = imfindcircles(leftImgModSob,[10,500],ObjectPolarity="bright",Sensitivity=0.9)
                [rightC, rightR, rightM] = imfindcircles(rightImgModSob,[10,500],ObjectPolarity="bright",Sensitivity=0.9)
    
                imagesc(app.UIAxesLeftMod, leftImgModSob);
                imagesc(app.UIAxesRightMod, rightImgModSob);
    
                hold(app.UIAxesLeftMod, 'on');
                hold(app.UIAxesRightMod, 'on'); 
                
                
                if (1 == app.strongest)
                    leftC = leftC(1,:); 
                    leftR = leftR(1);
                    leftM = leftM(1);
                end
                scatter(app.UIAxesLeftMod, leftC(:,1), leftC(:,2));
                T = table(leftC(:,1), leftC(:,2));
                app.UITableLeft.Data = T;
                scatter(app.UIAxesLeftMod, leftC(:,1), leftC(:,2));
                
                if (1 == app.strongest)
                    rightC = rightC(1,:); 
                    rightR = rightR(1);
                    rightM = rightM(1);
                end
                scatter(app.UIAxesRightMod, rightC(:,1), rightC(:,2));
                T = table(rightC(:,1), rightC(:,2));
                app.UITableRight.Data = T;

                viscircles(app.UIAxesLeftMod, leftC, leftR);
                viscircles(app.UIAxesRightMod, rightC, rightR); 
    
                hold(app.UIAxesLeftMod, 'off');
                hold(app.UIAxesRightMod, 'off');
            else
                [leftC, leftR, leftM] = imfindcircles(leftImgMod,[10,500],ObjectPolarity="bright",Sensitivity=0.9)
                [rightC, rightR, rightM] = imfindcircles(rightImgMod,[10,500],ObjectPolarity="bright",Sensitivity=0.9)
    
                imagesc(app.UIAxesLeftMod, leftImgMod);
                imagesc(app.UIAxesRightMod, rightImgMod);
    
                hold(app.UIAxesLeftMod, 'on');
                hold(app.UIAxesRightMod, 'on'); 
                
                if (1 == app.strongest)
                    leftC = leftC(1,:); 
                    leftR = leftR(1);
                    leftM = leftM(1);
                end
                T = table(leftC(:,1), leftC(:,2));
                app.UITableLeft.Data = T;
                scatter(app.UIAxesLeftMod, leftC(:,1), leftC(:,2));
                
                if (1 == app.strongest)
                    rightC = rightC(1,:); 
                    rightR = rightR(1);
                    rightM = rightM(1);
                end
                scatter(app.UIAxesRightMod, rightC(:,1), rightC(:,2));
                T = table(rightC(:,1), rightC(:,2));
                app.UITableRight.Data = T;

                viscircles(app.UIAxesLeftMod, leftC, leftR);
                viscircles(app.UIAxesRightMod, rightC, rightR); 
    
                hold(app.UIAxesLeftMod, 'off');
                hold(app.UIAxesRightMod, 'off');
            end

            leftImgMod  = rgb2gray(leftImgMod);
            rightImgMod = rgb2gray(rightImgMod);
            leftImgMod = medfilt2(leftImgMod);
            rightImgMod = medfilt2(rightImgMod);
            leftImgMod = filter2(fspecial('average',3),leftImgMod)/255;
            rightImgMod = filter2(fspecial('average',3),rightImgMod)/255;

            leftImgMod = imbinarize(leftImgMod);
            rightImgMod = imbinarize(rightImgMod);
            
            leftCentroid = app.hBlob(leftImgMod);
            rightCentroid = app.hBlob(rightImgMod);

            imagesc(app.UIAxesLeftBlob, leftImgMod);
            hold(app.UIAxesLeftBlob, "on");
            scatter(app.UIAxesLeftBlob, leftCentroid(:,1), leftCentroid(:,2))
            imagesc(app.UIAxesRightBlob, rightImgMod);
            hold(app.UIAxesRightBlob, "on");
            scatter(app.UIAxesRightBlob, rightCentroid(:,1), rightCentroid(:,2));
            hold(app.UIAxesLeftBlob, "off");
            hold(app.UIAxesRightBlob, "off");

            if (1 == app.strongest)
                d = (abs((leftC(1,1) - app.xNumPix/2) - (rightC(1,1) - app.xNumPix/2))*app.ps); %disparity [mm]
                Z = (app.b * app.f) ./ d; %depth [mm]
                calcZ = Z/1000;
                calcXL = (calcZ * ((leftC(1,1) - app.xNumPix/2)*app.ps)/app.f) - app.b/2000;
                calcYL = (calcZ * ((leftC(1,2) - app.yNumPix/2)*app.ps)/app.f);
                T = table(calcXL, calcYL, calcZ);
                app.UITableClac.Data = T;
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xNumPix = 752;
            app.yNumPix = 480;

            app.b = 60;             %baseline [mm]
            app.f = 6;              %focal length [mm]
            app.ps = 0.006;         %pixel size [mm]


            xlim(app.UIAxesLeftMain, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesLeftMain, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesRightMain, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesRightMain, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesLeftMod, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesLeftMod, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesRightMod, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesRightMod, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesLeftBlob, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesLeftBlob, [0 (app.yNumPix-1)]);
            xlim(app.UIAxesRightBlob, [0 (app.xNumPix-1)]);
            ylim(app.UIAxesRightBlob, [0 (app.yNumPix-1)]);

            app.leftImds = imageDatastore({'labAssignment3\left5.jpg','labAssignment3\left6.jpg','labAssignment3\left7.jpg','labAssignment3\left8.jpg','labAssignment3\left9.jpg'});
            app.rightImds = imageDatastore({'labAssignment3\right5.jpg','labAssignment3\right6.jpg','labAssignment3\right7.jpg','labAssignment3\right8.jpg','labAssignment3\right9.jpg'});
            
            app.imageIndex = app.SpinnerIndex.Value;

            app.redMinValue   = app.RedMinSlider.Value;
            app.redMaxValue   = app.RedMaxSlider.Value;
            app.greenMinValue = app.GreenMinSlider.Value;
            app.greenMaxValue = app.GreenMaxSlider.Value;
            app.blueMinValue  = app.BlueMinSlider.Value;
            app.blueMaxValue  = app.BlueMaxSlider.Value;
            app.sobelTrue = 0;
            app.strongest = 0;

            app.hBlob = vision.BlobAnalysis('AreaOutputPort',false,'BoundingBoxOutputPort',false, 'MinimumBlobArea', 200);


            app.leftImg  = readimage(app.leftImds, app.imageIndex);
            app.rightImg = readimage(app.rightImds, app.imageIndex);
            imagesc(app.UIAxesLeftMain, app.leftImg);
            imagesc(app.UIAxesRightMain, app.rightImg);
            modify_img(app)
        end

        % Value changed function: SpinnerIndex
        function SpinnerIndexValueChanged(app, event)
            app.imageIndex = app.SpinnerIndex.Value;
            app.leftImg  = readimage(app.leftImds, app.imageIndex);
            app.rightImg = readimage(app.rightImds, app.imageIndex);
            imagesc(app.UIAxesLeftMain, app.leftImg);
            imagesc(app.UIAxesRightMain, app.rightImg);
            modify_img(app)
        end

        % Value changed function: RedMinSlider
        function RedMinSliderValueChanged(app, event)
            app.redMinValue = app.RedMinSlider.Value;
            modify_img(app)
        end

        % Value changed function: RedMaxSlider
        function RedMaxSliderValueChanged(app, event)
            app.redMaxValue = app.RedMaxSlider.Value;
            modify_img(app)
        end

        % Value changed function: BlueMaxSlider
        function BlueMaxSliderValueChanged(app, event)
            app.blueMaxValue = app.BlueMaxSlider.Value;
            modify_img(app)
        end

        % Value changed function: BlueMinSlider
        function BlueMinSliderValueChanged(app, event)
            app.blueMinValue = app.BlueMinSlider.Value;
            modify_img(app)
        end

        % Value changed function: GreenMaxSlider
        function GreenMaxSliderValueChanged(app, event)
            app.greenMaxValue = app.GreenMaxSlider.Value;
            modify_img(app)
        end

        % Value changed function: GreenMinSlider
        function GreenMinSliderValueChanged(app, event)
            app.greenMinValue = app.GreenMinSlider.Value;
            modify_img(app)
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            app.sobelTrue = xor(1,app.sobelTrue);
            modify_img(app)
        end

        % Button pushed function: StrongestButton
        function StrongestButtonPushed(app, event)
            app.strongest = xor(1,app.strongest);
            modify_img(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLeftMain = uiaxes(app.UIFigure);
            title(app.UIAxesLeftMain, 'Left Original')
            xlabel(app.UIAxesLeftMain, 'X')
            ylabel(app.UIAxesLeftMain, 'Y')
            zlabel(app.UIAxesLeftMain, 'Z')
            app.UIAxesRightMain = uiaxes(app.UIFigure);
            title(app.UIAxesRightMain, 'Right Original')
            xlabel(app.UIAxesRightMain, 'X')
            ylabel(app.UIAxesRightMain, 'Y')
            zlabel(app.UIAxesRightMain, 'Z')
            app.UIAxesLeftMod = uiaxes(app.UIFigure);
            title(app.UIAxesLeftMod, 'Left Modified')
            xlabel(app.UIAxesLeftMod, 'X')
            ylabel(app.UIAxesLeftMod, 'Y')
            zlabel(app.UIAxesLeftMod, 'Z')
            app.UIAxesRightMod = uiaxes(app.UIFigure);
            title(app.UIAxesRightMod, 'Right Modified')
            xlabel(app.UIAxesRightMod, 'X')
            ylabel(app.UIAxesRightMod, 'Y')
            zlabel(app.UIAxesRightMod, 'Z')
            app.UIAxesRightBlob = uiaxes(app.UIFigure);
            title(app.UIAxesRightBlob, 'Right Blob')
            xlabel(app.UIAxesRightBlob, 'X')
            ylabel(app.UIAxesRightBlob, 'Y')
            zlabel(app.UIAxesRightBlob, 'Z')
            app.UIAxesLeftBlob = uiaxes(app.UIFigure);
            title(app.UIAxesLeftBlob, 'Left Blob')
            xlabel(app.UIAxesLeftBlob, 'X')
            ylabel(app.UIAxesLeftBlob, 'Y')
            zlabel(app.UIAxesLeftBlob, 'Z')
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.SpinnerIndex = uispinner(app.UIFigure);
            app.SpinnerIndex.Limits = [1 5];
            app.SpinnerIndex.ValueChangedFcn = createCallbackFcn(app, @SpinnerIndexValueChanged, true);
            app.SpinnerIndex.Value = 1;
            app.SobelButton = uibutton(app.UIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.RedMinSliderLabel = uilabel(app.UIFigure);
            app.RedMinSliderLabel.Text = 'Red Min';
            app.RedMinSlider = uislider(app.UIFigure);
            app.RedMinSlider.Limits = [0 255];
            app.RedMinSlider.ValueChangedFcn = createCallbackFcn(app, @RedMinSliderValueChanged, true);
            app.RedMaxSliderLabel = uilabel(app.UIFigure);
            app.RedMaxSliderLabel.Text = 'Red Max';
            app.RedMaxSlider = uislider(app.UIFigure);
            app.RedMaxSlider.Limits = [0 255];
            app.RedMaxSlider.ValueChangedFcn = createCallbackFcn(app, @RedMaxSliderValueChanged, true);
            app.RedMaxSlider.Value = 255;
            app.GreenMinSliderLabel = uilabel(app.UIFigure);
            app.GreenMinSliderLabel.Text = 'Green Min';
            app.GreenMinSlider = uislider(app.UIFigure);
            app.GreenMinSlider.Limits = [0 255];
            app.GreenMinSlider.ValueChangedFcn = createCallbackFcn(app, @GreenMinSliderValueChanged, true);
            app.GreenMaxSliderLabel = uilabel(app.UIFigure);
            app.GreenMaxSliderLabel.Text = 'Green Max';
            app.GreenMaxSlider = uislider(app.UIFigure);
            app.GreenMaxSlider.Limits = [0 255];
            app.GreenMaxSlider.ValueChangedFcn = createCallbackFcn(app, @GreenMaxSliderValueChanged, true);
            app.GreenMaxSlider.Value = 255;
            app.BlueMinSliderLabel = uilabel(app.UIFigure);
            app.BlueMinSliderLabel.Text = 'Blue Min';
            app.BlueMinSlider = uislider(app.UIFigure);
            app.BlueMinSlider.Limits = [0 255];
            app.BlueMinSlider.ValueChangedFcn = createCallbackFcn(app, @BlueMinSliderValueChanged, true);
            app.BlueMaxSliderLabel = uilabel(app.UIFigure);
            app.BlueMaxSliderLabel.Text = 'Blue Max';
            app.BlueMaxSlider = uislider(app.UIFigure);
            app.BlueMaxSlider.Limits = [0 255];
            app.BlueMaxSlider.ValueChangedFcn = createCallbackFcn(app, @BlueMaxSliderValueChanged, true);
            app.BlueMaxSlider.Value = 255;
            app.UITableLeft = uitable(app.UIFigure);
            app.UITableLeft.ColumnName = {'Left Centroid px'; 'Left Cetroid py'};
            app.UITableLeft.RowName = {};
            app.StrongestButton = uibutton(app.UIFigure, 'push');
            app.StrongestButton.ButtonPushedFcn = createCallbackFcn(app, @StrongestButtonPushed, true);
            app.StrongestButton.Text = 'Strongest';
            app.UITableRight = uitable(app.UIFigure);
            app.UITableRight.ColumnName = {'Right Centroid px'; 'Right Cetroid py'};
            app.UITableRight.RowName = {};
            app.UITableClac = uitable(app.UIFigure);
            app.UITableClac.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITableClac.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labAssignment3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end